classdef GUIburger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ZButton_minus                  matlab.ui.control.Button
        ZButton_plus                   matlab.ui.control.Button
        YButton_minus                  matlab.ui.control.Button
        YButton_plus                   matlab.ui.control.Button
        XButton_minus                  matlab.ui.control.Button
        XButton_plus                   matlab.ui.control.Button
        GetControlSwitch               matlab.ui.control.Switch
        GetControlSwitchLabel          matlab.ui.control.Label
        Joint7Slider                   matlab.ui.control.Slider
        Joint7SliderLabel              matlab.ui.control.Label
        Joint6Slider                   matlab.ui.control.Slider
        Joint6SliderLabel              matlab.ui.control.Label
        Joint5Slider                   matlab.ui.control.Slider
        Joint5SliderLabel              matlab.ui.control.Label
        Joint4Slider                   matlab.ui.control.Slider
        Joint4SliderLabel              matlab.ui.control.Label
        Joint3Slider                   matlab.ui.control.Slider
        Joint3SliderLabel              matlab.ui.control.Label
        Joint2Slider                   matlab.ui.control.Slider
        Joint2SliderLabel              matlab.ui.control.Label
        Joint1Slider                   matlab.ui.control.Slider
        Joint1SliderLabel              matlab.ui.control.Label
        BURGERButton                   matlab.ui.control.Button
        ROBOTDropDown                  matlab.ui.control.DropDown
        ROBOTDropDownLabel             matlab.ui.control.Label
        PhysicalEStopButton            matlab.ui.control.Button
        CollisionDetectionSwitch       matlab.ui.control.Switch
        CollisionDetectionSwitchLabel  matlab.ui.control.Label
        ResumeButton                   matlab.ui.control.Button
        EmergencyStopButton            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       
        LinearUR3
        SecondRobot
        Environment

        EmergencyStopStatus;
        ResumeButtonStatus;
        personPlotted;
        detectCollision ;
        collisionState;
        Running ;               % Indicates if the simulation is running
        
        %Burger and Plate Position
        burger;
        burger_start;
        burger_end;
        v_1;  
        a_1;
        updatedPoints_1;
        EndEffector_1;
        plate;
        plate_start;
        plate_end;
        v_2;
        a_2;
        updatedPoints_2;
        EndEffector_2;


        % Robot teach
        SelectedRobotModel;
        % stateControl;
        currentJoint;
        targetJoint;
        numStepsTeach = 15;
        movementDistance = 0.1;

        % Arduino
        arduinoObj;
        timerObj;
    end

    
    methods (Access = private)
            %% Constructors   
 function RestaurantSimulation(app)
   q_Home_SecondRobot= zeros(1,6);
   q_current_SecondRobot= app.SecondRobot.model.getpos();
   q_matrix_SecondRobot= jtraj(q_current_SecondRobot,q_Home_SecondRobot,50);

   q_Home_LinearUR3= zeros(1,7);
   q_current_LinearUR3= app.LinearUR3.model.getpos();
   q_matrix_LinearUR3= jtraj(q_current_LinearUR3,q_Home_LinearUR3,50);

   for i=1:50
       app.SecondRobot.model.animate(q_matrix_SecondRobot(i,:));
       app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(q_matrix_SecondRobot(i, :)).T * trotx(pi/2) * troty(pi/2);
       app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(q_matrix_SecondRobot(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
       app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
       app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
       
       app.LinearUR3.model.animate(q_matrix_LinearUR3(i,:));
       app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
       app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
       app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
       app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());

       pause(0.01);
   end

    app.Pickup_PlaceBurger_SecondRobot();
    pause(1); % Pause to simulate time taken
 
    app.Pickup_PlacePlateAndBurger_LinearUR3e();
    pause(1);   
   end
   
            %% Position for Burger and Plate
            function BurgerandPlatePosition(app)
     % Load burger and plate models and place them in the environment
             [~, app.a_1, ~] = plyread('hamburger.ply', 'tri');
             app.v_1 = size(app.a_1, 1);  % Number of vertices
        
             [~, app.a_2, ~] = plyread('Plate Square.PLY', 'tri');
             app.v_2 = size(app.a_2, 1);  % Number of vertices
        
              % Define the burger positions
                BurgerLocation_1 = [-2.4, 2.5, 1.2;-2.5, 2.5, 1.2];
                BurgerLocation_2 = [-1, 2, 1.1+0.2;-1, 2.5, 1.1+0.2];
                
                % Define the Plate Location
                PlateLocation_1=[ -1, 2-0.15, 1+0.025+0.2 ; -1, 2.5-0.15, 1+0.025+0.2 ];
                % PlateLocation_2=[ 0.2, 2-0.15, 1+0.025+0.2  ; 0.2, 2.5-0.15, 1+0.025+0.2 ];
                PlateLocation_2=[ 0.2, 2-0.15, 1.025  ; 0.2, 2.5-0.15, 1.025 ];

             numBurger = size(BurgerLocation_1, 1);
             app.burger = gobjects(1, numBurger);  % Pre-allocate handles for Burger
             app.plate = gobjects(1, numBurger);  % Pre-allocate handles for Plate

             % Loop to place each burger and plate
             for i = 1:numBurger

                % Place the Burger using PlaceObject (for visual positioning)
                app.burger(i) = PlaceObject('hamburger.ply', BurgerLocation_1(i, :));
                % Store the starting and ending transformation matrices for the burger
                app.burger_start(:,:,i) = transl(BurgerLocation_1(i, 1), BurgerLocation_1(i, 2), BurgerLocation_1(i, 3))*troty(2*pi/3)*transl(-0.1,0,0);
                app.burger_end(:,:,i) = transl(BurgerLocation_2(i, 1), BurgerLocation_2(i, 2), BurgerLocation_2(i, 3));
        
                % Place the Plate using PlaceObject
                app.plate(i) = PlaceObject('Plate Square.PLY', PlateLocation_1(i, :));
                 % Store the starting and ending transformation matrices for the bricks
                 
                app.plate_start(:,:,i) = transl(PlateLocation_1(i, 1), PlateLocation_1(i, 2), PlateLocation_1(i, 3))*troty(pi/2)*trotx(-pi/2);
                app.plate_end(:,:,i) = transl(PlateLocation_2(i, 1), PlateLocation_2(i, 2), PlateLocation_2(i, 3))*troty(pi/2)*trotx(-pi/2);
             end 
end


%% SecondRobot pick up burgers from grill and put on the Plate
function Pickup_PlaceBurger_SecondRobot(app)
    % Simulates the process of picking up a burger and moving it to a new position
    
    % Close and open gripper trajectories
    open = zeros(1, 3);
    close = [-0.2513, 0.6912, -0.4398]; % Gripper close joint state
    close_gripper = jtraj(open, close, 50);
    open_gripper = jtraj(close, open, 50);

    disp('Start pickup burger');

    for j = 1:2
        disp(['Moving to burger ', num2str(j)]);
       
      
        % Move to burger start position using RMRC
        [qMatrix] = RMRCMove(app.burger_start(:, :, j), app.SecondRobot);
        for i = 1:size(qMatrix, 1)
            % Animate robot and update gripper position
            app.SecondRobot.model.animate(qMatrix(i, :));
            app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
            app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
            app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
            app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
            pause(0.01);
            %Emergency stop button checking state
            app.checkEmergencyStop();
            if app.EmergencyStopStatus == true
                return;  % Exit the for loop if emergency stop is activated

            end
           
        
        end

        % Close Gripper and Pick up the burger
        disp(['Picking up Burger ', num2str(j)]);
        for i = 1:50
            app.checkEmergencyStop();
            if app.EmergencyStopStatus
                return;  % Exit the for loop if emergency stop is activated
            end
            app.SecondRobot.GRIPPERL.animate(close_gripper(i, :));
            app.SecondRobot.GRIPPERR.animate(close_gripper(i, :));
            pause(0.01);
        end

        % Attach the burger to the end-effector
        app.EndEffector_1 = app.SecondRobot.model.fkine(app.SecondRobot.model.getpos).T;
        app.updatedPoints_1 = [app.EndEffector_1 * troty(2*pi/3)*transl(-0.1,0,0.0)*[app.a_1, ones(app.v_1, 1)]']';  % Apply transformation to the burger's vertices
        app.burger(j).Vertices = app.updatedPoints_1(:, 1:3); % Update the burger's position

        % Move to drop-off position using RMRC
        disp(['Moving to burger drop-off position ', num2str(j)]);
        [qMatrix] = RMRCMove(app.burger_end(:, :, j), app.SecondRobot);
        for i = 1:size(qMatrix, 1)
             app.checkEmergencyStop();
            if app.EmergencyStopStatus
                return;  % Exit the for loop if emergency stop is activated
            end
            app.SecondRobot.model.animate(qMatrix(i, :));
            app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
            app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
            app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
            app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
            pause(0.01);
            % Update burger position while moving
        app.EndEffector_1 = app.SecondRobot.model.fkine(app.SecondRobot.model.getpos).T;
        app.updatedPoints_1 = [app.EndEffector_1 * troty(2*pi/3)*transl(-0.1,0,0.0)*[app.a_1, ones(app.v_1, 1)]']';  % Apply transformation to the burger's vertices
        app.burger(j).Vertices = app.updatedPoints_1(:, 1:3); % Update the burger's position

        end

        % Dropping off the burger
        disp(['Dropping off Burger ', num2str(j)]);
        for i = 1:50
             app.checkEmergencyStop();
        if app.EmergencyStopStatus
            
            return;  % Exit the for loop if emergency stop is activated
        end
            app.SecondRobot.GRIPPERL.animate(open_gripper(i, :));
            app.SecondRobot.GRIPPERR.animate(open_gripper(i, :));
            pause(0.01);
        end

        % Update burger position after drop-off
        app.updatedPoints_1 = [app.burger_end(:, :, j) * [app.a_1, ones(app.v_1, 1)]']';
        app.burger(j).Vertices = app.updatedPoints_1(:, 1:3);

         steps=50;
       % Return to home position [0, 0, 0, 0, 0, 0]
    homeQ = [0, 0, 0, 0, 0, 0];  % Home position joint configuration
    currentQ = app.SecondRobot.model.getpos();  % Get current joint state
    returnTrajectory = jtraj(currentQ, homeQ, steps);  % Create joint trajectory to home
    
    for i = 1:steps
         app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
        app.SecondRobot.model.animate(returnTrajectory(i, :));  % Animate robot back to home
        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(returnTrajectory(i, :)).T * trotx(pi/2) * troty(pi/2);
        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(returnTrajectory(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
        pause(0.01);
    end
    disp('Robot has returned to home position.');
    end
    end


%% LinearUR3 pick up plate and deliver to customer
function Pickup_PlacePlateAndBurger_LinearUR3e(app)

    % Gripper animation (close and open)
    open = zeros(1, 3);
    close = [-0.2513, 0.6912, -0.4398]; % Gripper close joint state
    close_gripper = jtraj(open, close, 100);
    open_gripper = jtraj(close, open, 100);

    disp('Start pick up the plates');

    %First movement, go straight along the linear
    [qMatrix]=RMRCMove(transl(-0.45, 1.786, 1.694),app.LinearUR3);
    for i=1:size(qMatrix,1)
         app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end

         app.LinearUR3.model.animate(qMatrix(i, :));
         app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
         app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
         app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
         app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
         pause(0.01)
    end
    
    %Second movement, the robot heads to pick up position
    for j = 1:2
        display(['Robot is Moving Plate ', num2str(j)]);
         app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
        % Move the robot to plate initial position
        % Get current joint sate of the UR3e 
        currentState = app.LinearUR3.model.getpos;
        q_0=jtraj(currentState,[ -2.0913         0   -1.1594   -0.4710         0         0    1.5708],100);
        
        % Moving to Plate initial position
        display(['Moving End Effector of  to Plate ', num2str(j), ' initial position']);
        for i = 1:100
             app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
            pause(0.01);
            app.LinearUR3.model.animate(q_0(i, :));
            app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
            app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
            app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
            app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
        end
        currentState_1 = app.LinearUR3.model.getpos;
        a = app.LinearUR3.model.ikcon(app.plate_start(:,:,j), currentState_1); % Calculation Joint state for brick start
        q_1 = jtraj(currentState_1, a, 100);
        for i = 1:100
             app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
            pause(0.01);
            app.LinearUR3.model.animate(q_1(i, :));
            app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
            app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
            app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
            app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
        end
        % Picking up the Plate with attached Burger
        display(['Picking Up Plate ', num2str(j)]);
        for i = 1:100
             app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
            pause(0.01);
            app.LinearUR3.gripperL.animate(close_gripper(i, :));
            app.LinearUR3.gripperR.animate(close_gripper(i, :));

        end
        %Update Plate Position
        EndEffector = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T; % Get current end-effector transformation matrix
        updatedPoints = [EndEffector*trotx(pi/2)*troty(-pi/2)*[app.a_2, ones(app.v_2, 1)]']';  % Apply transformation to the Plate's vertices
        app.plate(j).Vertices = updatedPoints(:, 1:3);% Update the plate's position
         
         % Apply the same transform to the burger vertices to attach with
         % movement
         
        app.updatedPoints_1 = [EndEffector * trotx(pi/2)*troty(-pi/2)*transl(0.025,0.15,0.1)*[app.a_1, ones(app.v_1, 1)]']';  % Apply transformation to burger vertices
        app.burger(j).Vertices = app.updatedPoints_1(:, 1:3);


        currentState_2= app.LinearUR3.model.getpos;
        q_2=jtraj(currentState_2,[-2.1619    0.0499         0   -1.5708    0.0005   -0.0015    3.0089],100);
        for i= 1:100
             app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
            pause(0.01);
            app.LinearUR3.model.animate(q_2(i, :));
            app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
            app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
            app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
            app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());

            EndEffector = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T; % Get current end-effector transformation matrix
            updatedPoints = [EndEffector*trotx(pi/2)*troty(-pi/2)*[app.a_2, ones(app.v_2, 1)]']';  % Apply transformation to the Plate's vertices
            app.plate(j).Vertices = updatedPoints(:, 1:3);% Update the plate's position
           

             % Apply the same transform to the burger vertices
        app.updatedPoints_1 = [EndEffector * trotx(pi/2)*troty(-pi/2)*transl(0.025,0.15,0.1)*[app.a_1, ones(app.v_1, 1)]']';  % Apply transformation to burger vertices
        app.burger(j).Vertices = app.updatedPoints_1(:, 1:3);
        end
        
       
        currentState_3=app.LinearUR3.model.getpos;
        q_3=jtraj(currentState_3,[-2.1619    0.0499    0.0000         0    0.0005   -0.0015    1.5708],100);
        for i=1:100
             app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
            app.LinearUR3.model.animate(q_3(i, :));
            app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
            app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
            app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
            app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());

            pause(0.01)

            EndEffector = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T; % Get current end-effector transformation matrix
            updatedPoints = [EndEffector*trotx(pi/2)*troty(-pi/2)*[app.a_2, ones(app.v_2, 1)]']';  % Apply transformation to the Plate's vertices
            app.plate(j).Vertices = updatedPoints(:, 1:3);% Update the plate's position
            
            
         % Apply the same transform to the burger vertices
        app.updatedPoints_1 = [EndEffector * trotx(pi/2)*troty(-pi/2)*transl(0.025,0.15,0.1)*[app.a_1, ones(app.v_1, 1)]']';  % Apply transformation to burger vertices
        app.burger(j).Vertices = app.updatedPoints_1(:, 1:3);


        end
        
        currentState_4=app.LinearUR3.model.getpos;
        q_4=jtraj(currentState_4,[-2.1619    0.0499    0    1.5708    0.0005   -0.0015    0],100);
         for i=1:100
              app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
            app.LinearUR3.model.animate(q_4(i, :));
            app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
            app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
            app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
            app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());

            pause(0.01)

            EndEffector = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T; % Get current end-effector transformation matrix
            updatedPoints = [EndEffector*trotx(pi/2)*troty(-pi/2)*[app.a_2, ones(app.v_2, 1)]']';  % Apply transformation to the Plate's vertices
            app.plate(j).Vertices = updatedPoints(:, 1:3);% Update the plate's position
            
             % Apply the same transform to the burger vertices
        app.updatedPoints_1 = [EndEffector * trotx(pi/2)*troty(-pi/2)*transl(0.025,0.15,0.1)*[app.a_1, ones(app.v_1, 1)]']';  % Apply transformation to burger vertices
        app.burger(j).Vertices = app.updatedPoints_1(:, 1:3);


         end

        currentState_5=app.LinearUR3.model.getpos;
        b = app.LinearUR3.model.ikcon(app.plate_end(:,:,j), currentState_5); % Calculation Joint state for brick start
        q_5 = jtraj(currentState_5, b, 100);
         for i= 1:100
              app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
            pause(0.01);
            app.LinearUR3.model.animate(q_5(i, :));
            app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
            app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
            app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
            app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
            EndEffector = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T; % Get current end-effector transformation matrix
            updatedPoints = [EndEffector*trotx(pi/2)*troty(-pi/2)*[app.a_2, ones(app.v_2, 1)]']';  % Apply transformation to the Plate's vertices
            app.plate(j).Vertices = updatedPoints(:, 1:3);% Update the plate's position
           
              % Apply the same transform to the burger vertices
        app.updatedPoints_1 = [EndEffector * trotx(pi/2)*troty(-pi/2)*transl(0.025,0.15,0.1)*[app.a_1, ones(app.v_1, 1)]']';  % Apply transformation to burger vertices
        app.burger(j).Vertices = app.updatedPoints_1(:, 1:3);


         end
        % Dropping off the brick
        display(['Dropping off Plate ', num2str(j)]);
        for i = 1:100
           app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
            pause(0.01);
            app.LinearUR3.gripperL.animate(open_gripper(i, :));
            app.LinearUR3.gripperR.animate(open_gripper(i, :));
        end
        updatedPoints = [app.plate_end(:,:,j)*trotx(pi/2)*troty(-pi/2) * [app.a_2, ones(app.v_2, 1)]']';
        app.plate(j).Vertices = updatedPoints(:, 1:3); 
        app.updatedPoints_1 = [EndEffector * trotx(pi/2)*troty(-pi/2)*transl(0.1,0.13,0.05)*[app.a_1, ones(app.v_1, 1)]']';  % Apply transformation to burger vertices
        app.burger(j).Vertices = app.updatedPoints_1(:, 1:3);


        q_6=jtraj(app.LinearUR3.model.getpos,currentState,100);
        for i= 1:100
           app.checkEmergencyStop();
        if app.EmergencyStopStatus == true
            app.ResumeButtonStatus = false;
            return;  % Exit the for loop if emergency stop is activated
           
        end
            pause(0.01);
            app.LinearUR3.model.animate(q_6(i, :));
            app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
            app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
            app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
            app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
            
        end
      
    end

end

%% Function to move SecondRobot with collision detection
        function MoveSecondRobotWithCollisionDetection(app)
         if app.detectCollision == false
            % Initialize robot position
            q = app.SecondRobot.model.getpos();
            
            % Load human.ply model
            hold on
            person = PlaceObject('personMaleConstruction.ply', [-2.5, 0, 0]);
            
            % Generate trajectory qMatrix for the robot
            q_Trans = transl(-2.5, -1, 2);
            q2 = app.SecondRobot.model.ikcon(q_Trans, q);
            q3 = jtraj(q, q2, 100);
           
            % Animate robot with collision detection
                for i = 1:100
                     app.checkEmergencyStop();
        if app.EmergencyStopStatus
            return;  % Exit the for loop if emergency stop is activated
        end
                   
                    collision = Collision_Dectection(app.SecondRobot, q3(i, :), person);
                    app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(app.SecondRobot.model.getpos).T *trotx(pi/2)*troty(pi/2);
                    app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(app.SecondRobot.model.getpos).T *trotz(pi)*trotx(pi/2)*troty(pi/2);
                    app.SecondRobot.GRIPPERL.animate( app.SecondRobot.GRIPPERL.getpos());
                    app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                    if collision == 1
                        disp('Collision detected 1 step ahead. Stopping animation.');
                        return; % Stop the animation if a collision is detected
                    
                   end
                     % Animate the robot step by step
                app.SecondRobot.model.animate(q3(i, :));
                pause(0.01);
                end
        end
               
            
    end

  %% Function to check Emergency Stop and halt all operations if active
    function checkEmergencyStop(app)
        if app.EmergencyStopStatus == true
            % Stop all movement
            disp('Emergency Stop Activated! Stopping all operations.');
            
            % Stop SecondRobot's movements
            if isfield(app, 'SecondRobot') && isobject(app.SecondRobot)
                % Stop any animations and hold the current position
                app.stopAnimation(app.SecondRobot);
            end
            
            % Stop LinearUR3's movements
            if isfield(app, 'LinearUR3') && isobject(app.LinearUR3)
                % Stop any animations and hold the current position
                app.stopAnimation(app.LinearUR3);
            end
            
            % Stop ongoing timers (if applicable)
            if isfield(app, 'SimulationTimer') && isvalid(app.SimulationTimer)
                stop(app.SimulationTimer);
                disp('Simulation Timer stopped.');
            end
            
            % Notify user that the robots have stopped due to emergency
            disp('All robots stopped due to emergency stop activation.');
        end
    end
    
    %% Helper function to stop robot animations and hold current position
    function stopAnimation(~, robot)
        % Stop any animations and hold the current joint state
        currentJointState = robot.model.getpos();
        robot.model.animate(currentJointState); % Hold at current state
        % robot.model.teach(); % Set the robot to a static pose to ensure no movement
    end

     %% Arduino Functions
        function startupArduino(app)
            app.arduinoObj = arduino('COM6', 'Uno');
            configurePin(app.arduinoObj, 'D2', 'pullup');
            configurePin(app.arduinoObj, 'D3', 'pullup');
            
            % Create a timer to read the button state every 0.1 seconds
            app.timerObj = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~,~)updateButtonState(app));
            start(app.timerObj);
        end
        
        function updateButtonState(app)
            boolean buttonState1;
            boolean buttonState2;
            buttonState1 = readDigitalPin(app.arduinoObj, 'D2');
            buttonState2 = readDigitalPin(app.arduinoObj, 'D3');
            if buttonState1 == 1
                disp('Button 1 is pressed');
                app.EmergencyStopStatus = true;
                app.EmergencyStopButton.Enable = 'off';
                app.ResumeButton.Enable = 'off';
                app.ResumeButtonStatus = false;
            else
                disp('Button 1 is not pressed');
            end 

            if buttonState2 == 1
                disp('Button 2 is pressed');
                app.EmergencyStopStatus = false;
                app.EmergencyStopButton.Enable = 'off';
                app.ResumeButton.Enable = 'on';
                app.ResumeButtonStatus = true;

%                 app.restartSimulation();
            else
                disp('Button 2 is not pressed');
            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axes(app.UIAxes);
            hold(app.UIAxes, 'on');   % Hold the plot
            app.Running = true;
            view(app.UIAxes, 48, 29);   % Change the view point
            
            % Setup flags
            app.EmergencyStopStatus = false;
            app.ResumeButtonStatus = false;
            app.personPlotted = false;
            app.detectCollision = false;

            % Initialize the button state
            app.Joint1Slider.Enable = 'off';
            app.Joint2Slider.Enable = 'off';
            app.Joint3Slider.Enable = 'off';
            app.Joint4Slider.Enable = 'off';
            app.Joint5Slider.Enable = 'off';
            app.Joint6Slider.Enable = 'off';
            app.Joint7Slider.Enable = 'off';

           
            app.ResumeButton.Enable = 'off';
            app.EmergencyStopButton.Enable = 'off';
            % app.RestartButton.Enable = 'off';
            app.CollisionDetectionSwitch.Enable = 'off';
            app.BURGERButton.Enable = 'off';     


            %% Create Restaurant Background
            app.SecondRobot= SecondRobot;
            app.LinearUR3= LinearUR3;
            app.Environment= Environment;
            app.BurgerandPlatePosition();
             
            %% Enable relevant UI controls
            app.EmergencyStopButton.Enable = 'on';
            % app.RestartButton.Enable = 'on';
            app.GetControlSwitch.Enable = "on";
            app.BURGERButton.Enable = 'on';
            app.CollisionDetectionSwitch.Enable ='on';
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
              if ~app.EmergencyStopStatus
                app.EmergencyStopStatus = true;
                app.ResumeButtonStatus = false;
                app.Running = false;
                disp('Emergency Stop Activated!');
                checkEmergencyStop(app);

              end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
           if app.EmergencyStopStatus == false
               disp('Emergency Stop is disengaged. Ready to resume.');
                % Allow resuming but not automatically
                app.EmergencyStopStatus = false;
                app.ResumeButtonStatus = true;
            end
        end

        % Value changed function: CollisionDetectionSwitch
        function CollisionDetectionSwitchValueChanged(app, event)
            MoveSecondRobotWithCollisionDetection(app)
            app.collisionState = app.CollisionDetectionSwitch.Value;
            
                if strcmp(app.collisionState,'On')
                    app.detectCollision = true;
                    fprintf('Collision detection activated.');
                    
                % Add logic to enable collision detection in the simulation
                else
                    app.detectCollision = false;
                    fprintf('Collision detection deactivated.');
                % Add logic to disable collision detection in the simulation
                end
           
        end

        % Button pushed function: BURGERButton
        function BURGERButtonPushed(app, event)
           
            RestaurantSimulation(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: GetControlSwitch
        function GetControlSwitchValueChanged(app, event)

           % while app.GetControlSwitch.Value
            if strcmp(app.GetControlSwitch.Value,'On')
                switch char(app.SelectedRobotModel)
                    case 'LinearUR3'
                        % disp('Get Control: LinearUR3')
                        
                        % view(48, 29);
                        % axis([-4 2 -5 5 0 3]) % change angle

                        app.Joint1Slider.Enable = 'on';
                        app.Joint2Slider.Enable = 'on';
                        app.Joint3Slider.Enable = 'on';
                        app.Joint4Slider.Enable = 'on';
                        app.Joint5Slider.Enable = 'on';
                        app.Joint6Slider.Enable = 'on';
                        app.Joint7Slider.Enable = 'on';
                      

                        app.Joint1Slider.Limits = [-3.1 0];
                        app.Joint2Slider.Limits = [-90 90];
                        app.Joint3Slider.Limits = [-90 90];
                        app.Joint4Slider.Limits = [-90 90];
                        app.Joint5Slider.Limits = [-90 90];
                        app.Joint6Slider.Limits = [-90 90];
                        app.Joint7Slider.Limits = [-180 180];
                        
                        % app.GripperSwitch.Enable = "on";
                        app.ROBOTDropDown.Enable = "on";

                    case 'SecondRobot'
                    
                        % disp('Get Control: SecondRobot')

                        % app.UIAxes.XLim = [0.5 2];
                        % app.UIAxes.YLim = [-1 1];
                        % app.UIAxes.ZLim = [0.5 1.5];


                        app.Joint1Slider.Enable = 'on';
                        app.Joint2Slider.Enable = 'on';
                        app.Joint3Slider.Enable = 'on';
                        app.Joint4Slider.Enable = 'on';
                        app.Joint5Slider.Enable = 'on';
                        app.Joint6Slider.Enable = 'on';
                        app.Joint7Slider.Enable = 'off';
                        app.Joint7SliderLabel.Visible='off';
                        app.Joint7Slider.Visible='off';

                        app.Joint1Slider.Limits = [-180 60];
                        app.Joint2Slider.Limits = [-45 15];
                        app.Joint3Slider.Limits = [-45 20];
                        app.Joint4Slider.Limits = [-180 180];
                        app.Joint5Slider.Limits = [-90 90];
                        app.Joint6Slider.Limits = [-180 180];
                        
                       % app.GripperSwitch.Enable = "on";
                       app.ROBOTDropDown.Enable = "on";

                end
            
            else
                app.Joint1Slider.Enable = 'off';
                app.Joint2Slider.Enable = 'off';
                app.Joint3Slider.Enable = 'off';
                app.Joint4Slider.Enable = 'off';
                app.Joint5Slider.Enable = 'off';
                app.Joint6Slider.Enable = 'off';
                app.Joint7Slider.Enable="off";
                app.Joint7Slider.Visible='on';
                app.Joint7SliderLabel.Visible='on';
                % app.GripperSwitch.Enable = "off";
                app.ROBOTDropDown.Enable = "off";
            end


        end

        % Value changed function: ROBOTDropDown
        function ROBOTDropDownValueChanged(app, event)
            selectedRobot = app.ROBOTDropDown.Value;  % Assuming the dropdown's name is "DropDown"

            % Store the selected robot model for later use
            app.SelectedRobotModel = selectedRobot;
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            joint1Angle = app.Joint1Slider.Value;
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    app.currentJoint = app.LinearUR3.model.getpos();
                    app.targetJoint = [deg2rad(joint1Angle) app.currentJoint(2:7)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end
                    
                case 'SecondRobot'
                    app.currentJoint = app.SecondRobot.model.getpos();
                    app.targetJoint = [deg2rad(joint1Angle) app.currentJoint(2:6)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
            
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            joint2Angle = app.Joint2Slider.Value;
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    app.currentJoint = app.LinearUR3.model.getpos();
                    app.targetJoint = [app.currentJoint(1) deg2rad(joint2Angle) app.currentJoint(3:7)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end

                case 'SecondRobot'
                    app.currentJoint = app.SecondRobot.model.getpos();
                    app.targetJoint = [app.currentJoint(1) deg2rad(joint2Angle) app.currentJoint(3:6)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
            
            
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            joint3Angle = app.Joint3Slider.Value;
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    app.currentJoint = app.LinearUR3.model.getpos();
                    app.targetJoint = [app.currentJoint(1:2) deg2rad(joint3Angle) app.currentJoint(4:7)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end
                    
                case 'SecondRobot'
                    app.currentJoint = app.SecondRobot.model.getpos();
                    app.targetJoint = [app.currentJoint(1:2) deg2rad(joint3Angle) app.currentJoint(4:6)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
            
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            joint4Angle = app.Joint4Slider.Value;
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    app.currentJoint = app.LinearUR3.model.getpos();
                    app.targetJoint = [app.currentJoint(1:3) deg2rad(joint4Angle) app.currentJoint(5:7)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end
                    
                case 'SecondRobot'
                    app.currentJoint = app.SecondRobot.model.getpos();
                    app.targetJoint = [app.currentJoint(1:3) deg2rad(joint4Angle) app.currentJoint(5:6)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            joint5Angle = app.Joint5Slider.Value;
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    app.currentJoint = app.LinearUR3.model.getpos();
                    app.targetJoint = [app.currentJoint(1:4) deg2rad(joint5Angle) app.currentJoint(6:7)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end
                    
                case 'SecondRobot'
                    app.currentJoint = app.SecondRobot.model.getpos();
                    app.targetJoint = [app.currentJoint(1:4) deg2rad(joint5Angle) app.currentJoint(6:6)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
            
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            joint6Angle = app.Joint6Slider.Value;
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    app.currentJoint = app.LinearUR3.model.getpos();
                    app.targetJoint = [app.currentJoint(1:5) deg2rad(joint6Angle) app.currentJoint(7:7)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end
                    
                case 'SecondRobot'
                    app.currentJoint = app.SecondRobot.model.getpos();
                    app.targetJoint = [app.currentJoint(1:5) deg2rad(joint6Angle)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
            
        end

        % Value changed function: Joint7Slider
        function Joint7SliderValueChanged(app, event)
            joint7Angle = app.Joint7Slider.Value;
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    app.currentJoint = app.LinearUR3.model.getpos();
                    app.targetJoint = [app.currentJoint(1:6) deg2rad(joint7Angle)];

                    qMatrix = MoveTrap(app.currentJoint,app.targetJoint,steps,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end
                    
                case 'SecondRobot'
                    disp('No joint 7...')
            end
            
        end

        % Button pushed function: XButton_plus
        function XButton_plusPushed(app, event)
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    t1 = app.LinearUR3.model.getpos();
                    pose1tr = app.LinearUR3.model.fkine(t1).T;
                    pose1tr(1,4) = pose1tr(1,4) + app.movementDistance;
                    t2 = app.LinearUR3.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end

                case 'SecondRobot'
                    t1 = app.SecondRobot.model.getpos();
                    pose1tr = app.SecondRobot.model.fkine(t1).T;
                    pose1tr(1,4) = pose1tr(1,4) + app.movementDistance;
                    t2 = app.SecondRobot.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
        end

        % Button pushed function: XButton_minus
        function XButton_minusPushed(app, event)
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    t1 = app.LinearUR3.model.getpos();
                    pose1tr = app.LinearUR3.model.fkine(t1).T;
                    pose1tr(1,4) = pose1tr(1,4) + app.movementDistance;
                    t2 = app.LinearUR3.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end

                case 'SecondRobot'
                    t1 = app.SecondRobot.model.getpos();
                    pose1tr = app.SecondRobot.model.fkine(t1).T;
                    pose1tr(1,4) = pose1tr(1,4) + app.movementDistance;
                    t2 = app.SecondRobot.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
        end

        % Button pushed function: YButton_plus
        function YButton_plusPushed(app, event)
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    t1 = app.LinearUR3.model.getpos();
                    pose1tr = app.LinearUR3.model.fkine(t1).T;
                    pose1tr(2,4) = pose1tr(2,4) + app.movementDistance;
                    t2 = app.LinearUR3.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end

                case 'SecondRobot'
                    t1 = app.SecondRobot.model.getpos();
                    pose1tr = app.SecondRobot.model.fkine(t1).T;
                    pose1tr(2,4) = pose1tr(2,4) + app.movementDistance;
                    t2 = app.SecondRobot.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
        end

        % Button pushed function: YButton_minus
        function YButton_minusPushed(app, event)
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    t1 = app.LinearUR3.model.getpos();
                    pose1tr = app.LinearUR3.model.fkine(t1).T;
                    pose1tr(2,4) = pose1tr(2,4) - app.movementDistance;
                    t2 = app.LinearUR3.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end

                case 'SecondRobot'
                    t1 = app.SecondRobot.model.getpos();
                    pose1tr = app.SecondRobot.model.fkine(t1).T;
                    pose1tr(2,4) = pose1tr(2,4) - app.movementDistance;
                    t2 = app.SecondRobot.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
        end

        % Button pushed function: ZButton_plus
        function ZButton_plusPushed(app, event)
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    t1 = app.LinearUR3.model.getpos();
                    pose1tr = app.LinearUR3.model.fkine(t1).T;
                    pose1tr(3,4) = pose1tr(3,4) + app.movementDistance;
                    t2 = app.LinearUR3.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end

                case 'SecondRobot'
                    t1 = app.SecondRobot.model.getpos();
                    pose1tr = app.SecondRobot.model.fkine(t1).T;
                    pose1tr(3,4) = pose1tr(3,4) + app.movementDistance;
                    t2 = app.SecondRobot.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
        end

        % Button pushed function: ZButton_minus
        function ZButton_minusPushed(app, event)
            steps = app.numStepsTeach;
            switch char(app.SelectedRobotModel)
                case 'LinearUR3'
                    t1 = app.LinearUR3.model.getpos();
                    pose1tr = app.LinearUR3.model.fkine(t1).T;
                    pose1tr(3,4) = pose1tr(3,4) - app.movementDistance;
                    t2 = app.LinearUR3.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.LinearUR3);

                    for i = 1:steps
                        app.LinearUR3.model.animate(qMatrix(i,:));
                        app.LinearUR3.gripperL.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotx(pi/2);
                        app.LinearUR3.gripperR.base = app.LinearUR3.model.fkine(app.LinearUR3.model.getpos).T * trotz(pi) * trotx(pi/2);
                        app.LinearUR3.gripperL.animate(app.LinearUR3.gripperL.getpos());
                        app.LinearUR3.gripperR.animate(app.LinearUR3.gripperR.getpos());
                        pause(0.001);
                        drawnow;
                    end

                case 'SecondRobot'
                    t1 = app.SecondRobot.model.getpos();
                    pose1tr = app.SecondRobot.model.fkine(t1).T;
                    pose1tr(3,4) = pose1tr(3,4) - app.movementDistance;
                    t2 = app.SecondRobot.model.ikcon(pose1tr,t1);

                    qMatrix = MoveTrap(t1,t2,app.numStepsTeach,app.SecondRobot);

                    for i = 1:steps
                        app.SecondRobot.model.animate(qMatrix(i,:));
                        app.SecondRobot.GRIPPERL.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERR.base = app.SecondRobot.model.fkine(qMatrix(i, :)).T * trotz(pi) * trotx(pi/2) * troty(pi/2);
                        app.SecondRobot.GRIPPERL.animate(app.SecondRobot.GRIPPERL.getpos());
                        app.SecondRobot.GRIPPERR.animate(app.SecondRobot.GRIPPERR.getpos());
                        pause(0.1);
                        drawnow;
                    end
            end
        end

        % Button pushed function: PhysicalEStopButton
        function PhysicalEStopButtonPushed(app, event)
            app.startupArduino;
            app.updateButtonState; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.CollisionDetectionSwitchLabel = uilabel(app.UIFigure);
            app.CollisionDetectionSwitchLabel.Text = 'Collision Detection';
            app.CollisionDetectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.CollisionDetectionSwitch.ValueChangedFcn = createCallbackFcn(app, @CollisionDetectionSwitchValueChanged, true);
            app.PhysicalEStopButton = uibutton(app.UIFigure, 'push');
            app.PhysicalEStopButton.ButtonPushedFcn = createCallbackFcn(app, @PhysicalEStopButtonPushed, true);
            app.PhysicalEStopButton.Text = 'Physical E-Stop';
            app.ROBOTDropDownLabel = uilabel(app.UIFigure);
            app.ROBOTDropDownLabel.Text = 'ROBOT';
            app.ROBOTDropDown = uidropdown(app.UIFigure);
            app.ROBOTDropDown.Items = {'LinearUR3', 'SecondRobot'};
            app.ROBOTDropDown.ValueChangedFcn = createCallbackFcn(app, @ROBOTDropDownValueChanged, true);
            app.ROBOTDropDown.Value = 'LinearUR3';
            app.BURGERButton = uibutton(app.UIFigure, 'push');
            app.BURGERButton.ButtonPushedFcn = createCallbackFcn(app, @BURGERButtonPushed, true);
            app.BURGERButton.Text = 'BURGER';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-3.1 0];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-90 90];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-90 90];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-90 90];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint7SliderLabel = uilabel(app.UIFigure);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.UIFigure);
            app.Joint7Slider.Limits = [-180 180];
            app.Joint7Slider.ValueChangedFcn = createCallbackFcn(app, @Joint7SliderValueChanged, true);
            app.GetControlSwitchLabel = uilabel(app.UIFigure);
            app.GetControlSwitchLabel.Text = 'Get Control';
            app.GetControlSwitch = uiswitch(app.UIFigure, 'slider');
            app.GetControlSwitch.ValueChangedFcn = createCallbackFcn(app, @GetControlSwitchValueChanged, true);
            app.XButton_plus = uibutton(app.UIFigure, 'push');
            app.XButton_plus.ButtonPushedFcn = createCallbackFcn(app, @XButton_plusPushed, true);
            app.XButton_plus.Text = 'X+';
            app.XButton_minus = uibutton(app.UIFigure, 'push');
            app.XButton_minus.ButtonPushedFcn = createCallbackFcn(app, @XButton_minusPushed, true);
            app.XButton_minus.Text = 'X-';
            app.YButton_plus = uibutton(app.UIFigure, 'push');
            app.YButton_plus.ButtonPushedFcn = createCallbackFcn(app, @YButton_plusPushed, true);
            app.YButton_plus.Text = 'Y+';
            app.YButton_minus = uibutton(app.UIFigure, 'push');
            app.YButton_minus.ButtonPushedFcn = createCallbackFcn(app, @YButton_minusPushed, true);
            app.YButton_minus.Text = 'Y-';
            app.ZButton_plus = uibutton(app.UIFigure, 'push');
            app.ZButton_plus.ButtonPushedFcn = createCallbackFcn(app, @ZButton_plusPushed, true);
            app.ZButton_plus.Text = 'Z+';
            app.ZButton_minus = uibutton(app.UIFigure, 'push');
            app.ZButton_minus.ButtonPushedFcn = createCallbackFcn(app, @ZButton_minusPushed, true);
            app.ZButton_minus.Text = 'Z-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIburger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end